classdef Image_Processing_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PixelValueStatsPanel     matlab.ui.container.Panel
        Edge                     matlab.ui.control.Label
        Unsharp                  matlab.ui.control.Label
        Original                 matlab.ui.control.Label
        StandardDeviation        matlab.ui.control.Label
        Mean                     matlab.ui.control.Label
        EdgeStdLabel             matlab.ui.control.Label
        UnsharpStdLabel          matlab.ui.control.Label
        OriginalStdLabel         matlab.ui.control.Label
        EdgeMeanLabel            matlab.ui.control.Label
        UnsharpMeanLabel         matlab.ui.control.Label
        OriginalMeanLabel        matlab.ui.control.Label
        EdgeThresholdSlider      matlab.ui.control.Slider
        EdgeThresholdLabel       matlab.ui.control.Label
        UnsharpStrengthSlider    matlab.ui.control.Slider
        AlphaLabel               matlab.ui.control.Label
        KernelSizepixelsSlider   matlab.ui.control.Slider
        KernelSizeLabel          matlab.ui.control.Label
        ApplyUnsharpCheckBox     matlab.ui.control.CheckBox
        ApplyEdgeCheckBox        matlab.ui.control.CheckBox
        ProcessImageButton       matlab.ui.control.Button
        IMAGEPROCESSINGAPPLabel  matlab.ui.control.Label
        OriginalImageLabel       matlab.ui.control.Label
        LoadImageButton          matlab.ui.control.Button
        UnsharpMaskingImageAxes  matlab.ui.control.UIAxes
        ProcessedImageAxes       matlab.ui.control.UIAxes
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            OriginalImage = [];  % stores the RGB/double image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add src folder to path so helper functions are visible
            appRoot = fileparts(mfilename('fullpath'));
            addpath(fullfile(appRoot, 'src'));

            % simple grey placeholder (300x300, mid-grey)
            placeholder = 0.2 * ones(300,300, 3);  % grayscale image
        
            % ----- Original image axes -----
            imagesc(app.ImageAxes, placeholder);
            axis(app.ImageAxes, 'image');  
            app.ImageAxes.TickDir       = 'none';
            app.ImageAxes.PickableParts = 'none';
            app.ImageAxes.Visible       = 'off';  
        
            % ----- Unsharp masking axes -----
            imagesc(app.UnsharpMaskingImageAxes, placeholder);
            axis(app.UnsharpMaskingImageAxes, 'image');
            app.UnsharpMaskingImageAxes.TickDir       = 'none';
            app.UnsharpMaskingImageAxes.PickableParts = 'none';
            app.UnsharpMaskingImageAxes.Visible       = 'off';
        
            % ----- Edge detection axes -----
            imagesc(app.ProcessedImageAxes, placeholder);
            axis(app.ProcessedImageAxes, 'image');
            app.ProcessedImageAxes.TickDir       = 'none';
            app.ProcessedImageAxes.PickableParts = 'none';
            app.ProcessedImageAxes.Visible       = 'off';

            % ---- Initialize stats labels ----
            app.OriginalMeanLabel.Text = "--";
            app.OriginalStdLabel.Text  = "--";
            
            app.UnsharpMeanLabel.Text  = "--";
            app.UnsharpStdLabel.Text   = "--";
            
            app.EdgeMeanLabel.Text     = "--";
            app.EdgeStdLabel.Text      = "--";
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file,path] = uigetfile(filterspec);
        
            % Handle cancel
            if isequal(file,0)
                return;
            end
        
            imagefile = [path file];
        
            try
                im = imread(imagefile);      % load image (uint8)
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure,ME.message,"Image Error");
                return;
            end
        
            % Convert to double in [0,1] for later processing
            I = im2double(im);
        
            % Store for later use in Process button
            app.OriginalImage = I;
        
            imagesc(app.ImageAxes, im);     % show original
            axis(app.ImageAxes, 'image');
            app.ImageAxes.TickDir = 'none';
            app.ImageAxes.PickableParts = 'none';
            app.ImageAxes.Visible = 'off';
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            % Make sure an image is loaded
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,"Please load an image first.","No Image");
                return;
            end
        
            % Read checkboxes
            applyUnsharp = logical(app.ApplyUnsharpCheckBox.Value);
            applyEdges   = logical(app.ApplyEdgeCheckBox.Value);
        
            % Image for processing
            I = app.OriginalImage;

            % Convert to grayscale for edge detection
            Igray = rgbToGrayLuminance(I);

            % Original Stats
            origMean = mean(I(:));
            origStd  = std(I(:));
            
            app.OriginalMeanLabel.Text = sprintf('%.3f', origMean);
            app.OriginalStdLabel.Text  = sprintf('%.3f', origStd); 

            % -----------------Edge Detection-----------------
            if applyEdges
                T = app.EdgeThresholdSlider.Value;
                E = prewittEdges(Igray, T);
        
                imagesc(app.ProcessedImageAxes, E);
                colormap(app.ProcessedImageAxes, gray);
                axis(app.ProcessedImageAxes, 'image');
                app.ProcessedImageAxes.Visible = 'off';
                
                % Edge Detection Stats
                app.EdgeMeanLabel.Text = sprintf('%.3f', mean(E(:)));
                app.EdgeStdLabel.Text  = sprintf('%.3f', std(double(E(:))));
            else
                cla(app.ProcessedImageAxes);
                app.ProcessedImageAxes.Visible = 'off';
                app.EdgeMeanLabel.Text = "--";
                app.EdgeStdLabel.Text  = "--";
            end
        
            %  --------------Unsharp Masking--------------
            if applyUnsharp
                k     = app.KernelSizepixelsSlider.Value;
                alpha = app.UnsharpStrengthSlider.Value;

                J = unsharpFilter(I, k, alpha);
        
                imagesc(app.UnsharpMaskingImageAxes, J);
                axis(app.UnsharpMaskingImageAxes, 'image');
                app.UnsharpMaskingImageAxes.Visible = 'off';
                
                % Unsharp Masking Stats
                app.UnsharpMeanLabel.Text = sprintf('%.3f', mean(J(:)));
                app.UnsharpStdLabel.Text  = sprintf('%.3f', std(J(:)));
            else
                cla(app.UnsharpMaskingImageAxes);
                app.UnsharpMaskingImageAxes.Visible = 'off';
                app.UnsharpMeanLabel.Text = "--";
                app.UnsharpStdLabel.Text  = "--";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ProcessedImageAxes = uiaxes(app.UIFigure);
            app.UnsharpMaskingImageAxes = uiaxes(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.IMAGEPROCESSINGAPPLabel = uilabel(app.UIFigure);
            app.IMAGEPROCESSINGAPPLabel.Text = 'IMAGE PROCESSING APP';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.ApplyEdgeCheckBox = uicheckbox(app.UIFigure);
            app.ApplyEdgeCheckBox.Text = 'Edge Detection';
            app.ApplyEdgeCheckBox.Value = true;
            app.ApplyUnsharpCheckBox = uicheckbox(app.UIFigure);
            app.ApplyUnsharpCheckBox.Text = 'Unsharp Mask';
            app.ApplyUnsharpCheckBox.Value = true;
            app.KernelSizeLabel = uilabel(app.UIFigure);
            app.KernelSizeLabel.Text = 'Kernel Size (pixels)';
            app.KernelSizepixelsSlider = uislider(app.UIFigure);
            app.KernelSizepixelsSlider.Limits = [3 30];
            app.KernelSizepixelsSlider.Value = 15;
            app.AlphaLabel = uilabel(app.UIFigure);
            app.AlphaLabel.Text = 'Unsharp Strength';
            app.UnsharpStrengthSlider = uislider(app.UIFigure);
            app.UnsharpStrengthSlider.Limits = [0 3];
            app.UnsharpStrengthSlider.Value = 1.5;
            app.EdgeThresholdLabel = uilabel(app.UIFigure);
            app.EdgeThresholdLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.UIFigure);
            app.EdgeThresholdSlider.Limits = [0.01 0.16];
            app.EdgeThresholdSlider.Value = 0.08;
            app.PixelValueStatsPanel = uipanel(app.UIFigure);
            app.PixelValueStatsPanel.Title = 'Pixel Value Stats';
            app.OriginalMeanLabel = uilabel(app.PixelValueStatsPanel);
            app.UnsharpMeanLabel = uilabel(app.PixelValueStatsPanel);
            app.EdgeMeanLabel = uilabel(app.PixelValueStatsPanel);
            app.OriginalStdLabel = uilabel(app.PixelValueStatsPanel);
            app.UnsharpStdLabel = uilabel(app.PixelValueStatsPanel);
            app.EdgeStdLabel = uilabel(app.PixelValueStatsPanel);
            app.Mean = uilabel(app.PixelValueStatsPanel);
            app.Mean.Text = 'Mean';
            app.StandardDeviation = uilabel(app.PixelValueStatsPanel);
            app.StandardDeviation.Text = 'Standard Deviation';
            app.Original = uilabel(app.PixelValueStatsPanel);
            app.Original.Text = 'Original:';
            app.Unsharp = uilabel(app.PixelValueStatsPanel);
            app.Unsharp.Text = 'Unsharp:';
            app.Edge = uilabel(app.PixelValueStatsPanel);
            app.Edge.Text = 'Edge:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Processing_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end